classdef defineCellROIs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuUIFigure                  matlab.ui.Figure
        FieldofViewLabel              matlab.ui.control.Label
        activitySlider                matlab.ui.control.Slider
        projectionSlider              matlab.ui.control.Slider
        cellsSlider                   matlab.ui.control.Slider
        cellsSliderLabel              matlab.ui.control.Label
        projectionSliderLabel         matlab.ui.control.Label
        AutomatedCellDetectionLabel   matlab.ui.control.Label
        windowsizeEditField           matlab.ui.control.NumericEditField
        minpixelsEditField            matlab.ui.control.NumericEditField
        maxpixelsEditField            matlab.ui.control.NumericEditField
        HmaximaEditField              matlab.ui.control.NumericEditField
        activitythresholdEditField    matlab.ui.control.NumericEditField
        activityoffsetEditField       matlab.ui.control.NumericEditField
        diskradiusEditField           matlab.ui.control.NumericEditField
        detectcellsButton             matlab.ui.control.Button
        RefineCellROIsLabel           matlab.ui.control.Label
        refineROIsButton              matlab.ui.control.StateButton
        zoominButton                  matlab.ui.control.StateButton
        zoomoutButton                 matlab.ui.control.Button
        resetButton                   matlab.ui.control.Button
        SaveCellDataLabel             matlab.ui.control.Label
        saveButton                    matlab.ui.control.Button
        ROIStabilityLabel             matlab.ui.control.Label
        calculateButton               matlab.ui.control.Button
        activitySliderLabel           matlab.ui.control.Label
        diskradiusEditFieldLabel      matlab.ui.control.Label
        activitythresholdEditFieldLabel  matlab.ui.control.Label
        HmaximaLabel                  matlab.ui.control.Label
        maxpixelsEditFieldLabel       matlab.ui.control.Label
        minpixelsEditFieldLabel       matlab.ui.control.Label
        windowsizeEditFieldLabel      matlab.ui.control.Label
        activityoffsetEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        
        % Root dir
        rootDir = [];
        
        % Mouse data
        mouseName = [];
        dates = [];
        blocks = [];
        
        % Figure
        fig = [];
        ax = [];
        I = [];
        map = [];
        
        % Registration
        referenceProjections = [];
        referenceProjectionsIDs = [];
        registeredProjections = [];
        registeredActivityMaps = [];
        rowShiftVector = [];
        columnShiftVector = [];
        
        % Cell detection
        activityMapThreshold = 0;       % threshold for activity map
        offset = -0.05;                 % offset for actuivity map
        diskRadius = 5;                 % radius for morphologial opening of activity map
        windowSize = 100;               % window size for filtering activity map
        minPixels = 200;                % minimum number of pixels for ROI size
        maxPixels = 400;                % maximum number of pixels for ROI size
        Hmaxima = 0;                    % threshold for extended-maxima transform
        
        % Cells
        averageActivityMap = [];
        ROIMap = [];
        cellROIs = [];
        reverseCellROIs = [];
        projectionIntensity = 35;

        % Cell ROI stability
        cellROIStability
        cellROIRandomizedStability
        
        % Freehand ROI
        h = [];
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mouseName, dates, blocks)
            
            % Root Dir
            app.rootDir = [pwd,'\exampleExperiment\'];
            
            % Mouse
            app.mouseName = mouseName;
        
            % Display
            fprintf('\n')
            disp('     Loading data...')
            
            % Registration across sessions
            [app.referenceProjections,app.referenceProjectionsIDs,app.registeredProjections,app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.dates,app.blocks] = registerSessions(mouseName,dates,blocks,app.rootDir);
            
            % Update image
            app.fig = uifigure('Name','Image');
            set(app.fig,'Position',[150 75 1000 950])
            app.ax = axes(app.fig);
            app.map = zeros(size(app.registeredProjections,1),size(app.registeredProjections,2),3);
            app.map(:,:,1) = mean(app.registeredProjections,3);
            app.map(:,:,2) = mean(app.registeredActivityMaps,3);
            app.map(:,:,3) = mean(app.registeredProjections,3);
            app.I = imagesc(app.ax,app.map);
            set(app.ax,'YDir','reverse'), axis(app.ax,'off')
            
            % Reduce projection intensity
            app.projectionSlider.Value = app.projectionIntensity;
            newMap = app.map;
            newMap(:,:,1) = (app.projectionIntensity/100)*app.map(:,:,1);
            newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
            app.I = imagesc(app.ax,newMap);

            fprintf('\n')
            
        end

        % Value changed function: activitythresholdEditField
        function activitythresholdEditFieldValueChanged(app, event)
            app.activityMapThreshold = app.activitythresholdEditField.Value;
        end

        % Value changed function: activityoffsetEditField
        function activityoffsetEditFieldValueChanged(app, event)
            app.offset = app.activityoffsetEditField.Value;
        end

        % Value changed function: diskradiusEditField
        function diskradiusEditFieldValueChanged(app, event)
            app.diskRadius = app.diskradiusEditField.Value;
        end

        % Value changed function: windowsizeEditField
        function windowsizeEditFieldValueChanged(app, event)
            app.windowSize = app.windowsizeEditField.Value;
        end

        % Value changed function: minpixelsEditField
        function minpixelsEditFieldValueChanged(app, event)
            app.minPixels = app.minpixelsEditField.Value;
        end

        % Value changed function: maxpixelsEditField
        function maxpixelsEditFieldValueChanged(app, event)
            app.maxPixels = app.maxpixelsEditField.Value;
        end

        % Value changed function: HmaximaEditField
        function HmaximaEditFieldValueChanged(app, event)
            app.Hmaxima = app.HmaximaEditField.Value;
        end

        % Button pushed function: detectcellsButton
        function detectcellsButtonPushed(app, event)

            % Look for previously defined ROIs
            
                % Since all average projections are aligned to the one corresponding to the first experiment
                if exist([app.rootDir,app.mouseName,'_',num2str(app.dates(1)),'_',num2str(app.blocks(1)),'.mat'],'file') > 0
                    load([app.rootDir,app.mouseName,'_',num2str(app.dates(1)),'_',num2str(app.blocks(1)),'.mat'])
                    
                    % Check for existing ROIs
                    if isfield(mouseData,'cellROIs')
                        answer = questdlg('Would you like to load existing ROIs?','Load ROIs','Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                app.cellROIs = mouseData.cellROIs;
                                app.ROIMap = zeros(size(mouseData.activityMap,1),size(mouseData.activityMap,2),'single');
                                for i=1:size(app.cellROIs,1)
                                    app.ROIMap = app.ROIMap + single(poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(mouseData.activityMap,1),size(mouseData.activityMap,2)));
                                end
                        end
                    end
                    clear('mouseData')
                    
                end
                
            if ~exist('answer','var') || strcmp(answer,'No')
                    % Automated cell detection
                    [app.averageActivityMap,app.ROIMap,app.cellROIs] = detectCells(app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.activityMapThreshold,app.offset,app.diskRadius,app.windowSize,app.minPixels,app.maxPixels,app.Hmaxima);
            elseif strcmp(answer,'Yes')
                    % Just get the activity map
                    [app.averageActivityMap] = detectCells(app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.activityMapThreshold,app.offset,app.diskRadius,app.windowSize,app.minPixels,app.maxPixels,app.Hmaxima);
            end
            
            % Update image
            app.map = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),3);
            app.map(:,:,1) = app.ROIMap;
            app.map(:,:,2) = app.averageActivityMap;
            app.map(:,:,3) = mean(app.registeredProjections,3);
            app.I = imagesc(app.ax,app.map);
            set(app.ax,'YDir','reverse'), axis(app.ax,'off')
            
            % Reduce projection intensity
            app.projectionSlider.Value = app.projectionIntensity;
            newMap = app.map;
            newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
            app.I = imagesc(app.ax,newMap);
            
            % Reset buttons
            app.refineROIsButton.Value = 0;
            app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
            app.refineROIsButton.FontColor = [0 0 0];
            app.zoominButton.Value = 0;
            app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
            app.zoominButton.FontColor = [0 0 0];
            zoom(app.ax,'off')
            
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            
            if ~isempty(app.cellROIs)
                
                answer = questdlg('Would you like to save the current ROIs?','Save','Yes','No','Yes');
                
                switch answer
                    
                    case 'Yes'

                        % Display
                        disp('     Reversing cell ROI coordinates to their original coordinates in individual experiments...')
                        wb = waitbar(0,'Checking for error ROIs...','Name','Reversing cell ROI coordinates...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(wb,'canceling',0);
                        
                        % Delete error ROIs
                        for i=1:size(app.cellROIs,1)
                            if size(app.cellROIs,1) >= i
                                ROI = poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2));
                                CC = bwconncomp(ROI,4);
                                if CC.NumObjects == 0
                                    app.cellROIs(i) = [];
                                end
                                clear('ROI','CC')
                            end
                        end
                        waitbar(0,wb,'Reversing cell ROI coordinates...')
                        
                        % Reverse registration of cell ROIs
                        app.reverseCellROIs = cell(size(app.registeredActivityMaps,3),1);
                        for j=1:size(app.registeredActivityMaps,3)
                            % Waitbar
                                % Check for clicked Cancel button
                                if getappdata(wb,'canceling')
                                    selection = questdlg('Do you want to stop this process?','Stop process','Yes','No','Yes');
                                        switch selection
                                            case 'Yes'
                                                delete(gcf)
                                                break
                                            case 'No'
                                                setappdata(wb,'canceling',0);
                                        end
                                end
                                % Update waitbar and message
                                waitbar(j/size(app.registeredActivityMaps,3),wb,['stack ',num2str(j),' out of ',num2str(size(app.registeredActivityMaps,3))])
                            stackCellROIs = app.cellROIs;
                            for i=1:size(app.cellROIs,1)
                                stackCellROIs{i}(:,1) = app.cellROIs{i}(:,1) - app.columnShiftVector(j);
                                stackCellROIs{i}(:,2) = app.cellROIs{i}(:,2) - app.rowShiftVector(j);
                            end
                            ROIs = zeros(size(app.registeredActivityMaps,1),size(app.registeredActivityMaps,2),'single');
                            for i=1:size(stackCellROIs,1)
                                ROIs = ROIs + single(poly2mask(stackCellROIs{i}(:,1),stackCellROIs{i}(:,2),size(app.registeredActivityMaps,1),size(app.registeredActivityMaps,2)));
                            end
                            app.reverseCellROIs{j} = stackCellROIs;
                            clear('stackCellROIs')
                        end
                        delete(wb)
                        
                        % Display
                        disp('     Saving cell ROIs...')
                        wb = waitbar(0,'Saving cell ROIs...','Name','Saving cell ROIs...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(wb,'canceling',0);
                        
                        % Save cell ROIs
                        for i=1:size(app.registeredActivityMaps,3)
                            % Waitbar
                                % Check for clicked Cancel button
                                if getappdata(wb,'canceling')
                                    selection = questdlg('Do you want to stop this process?','Stop process','Yes','No','Yes');
                                        switch selection
                                            case 'Yes'
                                                delete(gcf)
                                                break
                                            case 'No'
                                                setappdata(wb,'canceling',0);
                                        end
                                end
                                % Update waitbar and message
                                waitbar(i/size(app.registeredActivityMaps,3),wb,['stack ',num2str(i),' out of ',num2str(size(app.registeredActivityMaps,3))])
                            % Load and save
                            load([app.rootDir,app.mouseName,'_',num2str(app.dates(i)),'_',num2str(app.blocks(i)),'.mat'])
                            mouseData.cellROIs = app.reverseCellROIs{i};
                            save([app.rootDir,app.mouseName,'_',num2str(app.dates(i)),'_',num2str(app.blocks(i)),'.mat'],'mouseData')
                        end
                        delete(wb)
                        
                        % Display
                        disp('     Cell ROIs have been saved.')
                        fprintf('\n')
        
                    case 'No'
                        
                        % Continue working on cell RoIs...
                        
                end
                
            else

                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','No cell ROIs have been defined yet.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')
                
            end
            
        end

        % Value changed function: zoominButton
        function zoominButtonValueChanged(app, event)
        
            % Zoom
            if app.zoominButton.Value == 1 && app.refineROIsButton.Value == 0
                % Zoom in on
                zoom(app.ax,'on')
                app.zoominButton.BackgroundColor = [0 0.8 0];
                app.zoominButton.FontColor = [0.96 0.96 0.96];
            elseif app.zoominButton.Value == 1 && app.refineROIsButton.Value == 1
                    % Display
                    d = dialog('Position',[500 500 300 120],'Name','Warning');
                    uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','Please finish drawing your ROI first.');
                    uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                    clear('d')
                    % Turn button off
                    app.zoominButton.Value = 0;
                    app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
                    app.zoominButton.FontColor = [0 0 0];
            elseif app.zoominButton.Value == 0
                % Zoom in off
                zoom(app.ax,'off')
                app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
                app.zoominButton.FontColor = [0 0 0];
            end
            
        end

        % Value changed function: refineROIsButton
        function refineROIsButtonValueChanged(app, event)
            
            if app.refineROIsButton.Value == 1 && ~isempty(app.cellROIs)
                
                app.refineROIsButton.BackgroundColor = [1 0.1 0];
                app.refineROIsButton.FontColor = [0.96 0.96 0.96];

                % Turn zoom in off
                zoom(app.ax,'off')
                app.zoominButton.Value = 0;
                app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
                app.zoominButton.FontColor = [0 0 0];
                
                % Define ROI
                app.h = drawfreehand(app.ax,'Color',[1 0 0]);                
                
                if ~isempty(app.h.Position)
                
                    newROI = poly2mask(app.h.Position(:,1),app.h.Position(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2));
    
                    % Check for ROI existance (to prevent misclick errors)
                    CC = bwconncomp(newROI,4);
                    
                    if CC.NumObjects ~= 0
                    
                        % Check for complete encompassing of ROI
                        in = [];
                        for j = 1:length(app.cellROIs)
                            if min(newROI(poly2mask(app.cellROIs{j}(:,1),app.cellROIs{j}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2)))) == 1
                                in = cat(1,in,j);
                            end
                        end
                        if isempty(in)
                            % Update cell matrix
                            app.cellROIs{length(app.cellROIs)+1} = app.h.Position;
                        else
                            % Delete cell(s)
                            app.cellROIs(in) = [];
                            app.cellROIs = squeeze(app.cellROIs);
                        end
                        
                        % Update image
                        app.ROIMap = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),'single');
                        for i=1:size(app.cellROIs,1)
                            app.ROIMap = app.ROIMap + single(poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2)));
                        end
                        app.map = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),3);
                        app.map(:,:,1) = app.ROIMap;
                        app.map(:,:,2) = app.averageActivityMap;
                        app.map(:,:,3) = mean(app.registeredProjections,3);
                        
                        newMap = app.map;
                        
                        % Reduce projection intensity
                        app.projectionSlider.Value = app.projectionIntensity;
                        newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
                        
                        % Keep current intensity for activity map and average projection
                        x1 = app.activitySlider.Value;
                        x2 = app.cellsSlider.Value;
                        newMap(:,:,2) = (x1/100)*app.map(:,:,2);
                        newMap(:,:,1) = (x2/100)*app.map(:,:,1);
                        
                        % Display image
                        xLimits = get(app.ax,'XLim');
                        yLimits = get(app.ax,'YLim');
                        app.I = imagesc(app.ax,newMap);
                        set(app.ax,'YDir','reverse'), axis(app.ax,'off')
                        xlim(app.ax,xLimits)
                        ylim(app.ax,yLimits)
                        
                    else
                        
                        % Do nothing...
                    
                    end
                    
                    % Turn refine ROI off
                    app.refineROIsButton.Value = 0;
                    app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                    app.refineROIsButton.FontColor = [0 0 0];
                    
                end
                
            elseif isempty(app.cellROIs)
                
                % Turn refine ROI off
                app.refineROIsButton.Value = 0;
                app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                app.refineROIsButton.FontColor = [0 0 0];
                
                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','Please detect cells first.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')

            end
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            
            if isempty(app.cellROIs)
                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','No cell ROIs have been defined yet.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')
                answer = 'No';
            elseif ~isempty(app.cellROIs)
                answer = questdlg('Do you want to reset cell ROI detection?','Reset','Yes','No','No');
            end
                
            switch answer
                
                case 'Yes'
        
                    % Update image
                    app.map = zeros(size(app.registeredProjections,1),size(app.registeredProjections,2),3);
                    app.map(:,:,1) = mean(app.registeredProjections,3);
                    app.map(:,:,2) = mean(app.registeredActivityMaps,3);
                    app.map(:,:,3) = mean(app.registeredProjections,3);
                    app.I = imagesc(app.ax,app.map);
                    set(app.ax,'YDir','reverse'), axis(app.ax,'off')
                    
                    % Reduce projection intensity
                    app.projectionSlider.Value = app.projectionIntensity;
                    newMap = app.map;
                    newMap(:,:,1) = (app.projectionIntensity/100)*app.map(:,:,1);
                    newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
                    app.I = imagesc(app.ax,newMap);
                    
                    % Reset ROIs
                    app.cellROIs = [];
                    
                    % Reset buttons
                    app.refineROIsButton.Value = 0;
                    app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                    app.refineROIsButton.FontColor = [0 0 0];
                    app.zoominButton.Value = 0;
                    app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
                    app.zoominButton.FontColor = [0 0 0];
            
                case 'No'
                    
            end
        
        end

        % Button pushed function: zoomoutButton
        function zoomoutButtonPushed(app, event)

            % Zoom out
            app.ax.XLim = 0.5 + [0 size(app.registeredProjections,1)];
            app.ax.YLim = 0.5 + [0 size(app.registeredProjections,2)];
            
            % Turn zoom in off
            zoom(app.ax,'off')
            app.zoominButton.Value = 0;
            app.zoominButton.BackgroundColor = [0.96 0.96 0.96];
            app.zoominButton.FontColor = [0 0 0];
        
        end

        % Value changing function: activitySlider
        function activitySliderValueChanging(app, event)

            app.zoominButton.Value = 0;
            zoom(app.ax,'off')
            
            newMap = app.map;
            x1 = app.projectionSlider.Value;
            x2 = app.cellsSlider.Value;
            
            % Activity
            newMap(:,:,2) = (event.Value/100)*app.map(:,:,2);

            if isempty(app.cellROIs)
                % Projection
                newMap(:,:,1) = (x1/100)*app.map(:,:,1);
                newMap(:,:,3) = (x1/100)*app.map(:,:,3);
            else
                % Projection
                newMap(:,:,3) = (x1/100)*app.map(:,:,3);
                % Cells
                newMap(:,:,1) = (x2/100)*app.map(:,:,1);
            end
            
            % Update image
            xLimits = get(app.ax,'XLim');
            yLimits = get(app.ax,'YLim');
            app.I = imagesc(app.ax,newMap);
            xlim(app.ax,xLimits)
            ylim(app.ax,yLimits)
            
        end

        % Value changing function: projectionSlider
        function projectionSliderValueChanging(app, event)

            app.zoominButton.Value = 0;
            zoom(app.ax,'off')
        
            newMap = app.map;
            x1 = app.activitySlider.Value;
            x2 = app.cellsSlider.Value;
            
            if isempty(app.cellROIs)
                % Projection
                newMap(:,:,1) = (event.Value/100)*app.map(:,:,1);
                newMap(:,:,3) = (event.Value/100)*app.map(:,:,3);
                % Activity
                newMap(:,:,2) = (x1/100)*app.map(:,:,2);
            else
                % Projection
                newMap(:,:,3) = (event.Value/100)*app.map(:,:,3);
                % Activity
                newMap(:,:,2) = (x1/100)*app.map(:,:,2);
                % Cells
                newMap(:,:,1) = (x2/100)*app.map(:,:,1);
            end
            
            % Update image
            xLimits = get(app.ax,'XLim');
            yLimits = get(app.ax,'YLim');
            app.I = imagesc(app.ax,newMap);
            xlim(app.ax,xLimits)
            ylim(app.ax,yLimits)
            
        end

        % Value changing function: cellsSlider
        function cellsSliderValueChanging(app, event)

            if ~isempty(app.cellROIs)
                
                app.zoominButton.Value = 0;
                zoom(app.ax,'off')
                
                newMap = app.map;
                
                % Cells
                newMap(:,:,1) = (event.Value/100)*app.map(:,:,1);
                
                % Adjust projection
                x = app.projectionSlider.Value;
                newMap(:,:,3) = (x/100)*app.map(:,:,3);
                
                % Update image
                xLimits = get(app.ax,'XLim');
                yLimits = get(app.ax,'YLim');
                app.I = imagesc(app.ax,newMap);
                xlim(app.ax,xLimits)
                ylim(app.ax,yLimits)
                
            end
            
        end

        % Close request function: MenuUIFigure
        function MenuUIFigureCloseRequest(app, event)
            
            delete(app.fig)
            delete(app)
            
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            
            if ~isempty(app.cellROIs)

                answer = questdlg({['There are currently ',num2str(size(app.cellROIs,1)),' cell ROIs imaged across ',num2str(size(app.referenceProjections,3)),' sessions.'];
                                   ['Calculating ROI stability will take about ',num2str((3*(size(app.referenceProjections,3)-1)*size(app.cellROIs,1))/60),' min.'];
                                    '';
                                    'We recommend that you save your current ROIs before continuing.';
                                    '';
                                    'Would you like to proceed and calculate ROI stability?';},'ROI stability','Yes','No','Yes');
                
                switch answer
                    
                    case 'Yes'

                        app.calculateButton.BackgroundColor = [0 0.1 0.9];
                        app.calculateButton.FontColor = [0.96 0.96 0.96];

                        % Display
                        disp('     Reversing cell ROI coordinates to their original coordinates in individual experiments...')
                        wb = waitbar(0,'Checking for error ROIs...','Name','Reversing cell ROI coordinates...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(wb,'canceling',0);
                        
                        % Delete error ROIs
                        for i=1:size(app.cellROIs,1)
                            if size(app.cellROIs,1) >= i
                                ROI = poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2));
                                CC = bwconncomp(ROI,4);
                                if CC.NumObjects == 0
                                    app.cellROIs(i) = [];
                                end
                                clear('ROI','CC')
                            end
                        end
                        waitbar(0,wb,'Reversing cell ROI coordinates...')
                        
                        % Reverse registration of cell ROIs
                        app.reverseCellROIs = cell(length(app.referenceProjectionsIDs),1);
                        n = 0;
                        for j=app.referenceProjectionsIDs
                            n = n + 1;
                            % Waitbar
                                % Check for clicked Cancel button
                                if getappdata(wb,'canceling')
                                    selection = questdlg('Do you want to stop this process?','Stop process','Yes','No','Yes');
                                        switch selection
                                            case 'Yes'
                                                delete(gcf)
                                                break
                                            case 'No'
                                                setappdata(wb,'canceling',0);
                                        end
                                end
                                % Update waitbar and message
                                waitbar(n/length(app.referenceProjectionsIDs),wb,['stack ',num2str(n),' out of ',num2str(length(app.referenceProjectionsIDs))])
                            stackCellROIs = app.cellROIs;
                            for i=1:size(app.cellROIs,1)
                                stackCellROIs{i}(:,1) = app.cellROIs{i}(:,1) - app.columnShiftVector(j);
                                stackCellROIs{i}(:,2) = app.cellROIs{i}(:,2) - app.rowShiftVector(j);
                            end
                            ROIs = zeros(size(app.referenceProjections,1),size(app.referenceProjections,2),'single');
                            for i=1:size(stackCellROIs,1)
                                ROIs = ROIs + single(poly2mask(stackCellROIs{i}(:,1),stackCellROIs{i}(:,2),size(app.referenceProjections,1),size(app.referenceProjections,2)));
                            end
                            app.reverseCellROIs{n} = stackCellROIs;
                            clear('stackCellROIs')
                        end
                        delete(wb)
        
                        [app.cellROIStability,app.cellROIRandomizedStability] = calculateROIStability(app.reverseCellROIs,app.referenceProjections);

                        % Display
                        disp('     Saving cell ROI stability data...')
                        
                        % Save ROI stability data
                        cellROIError = app.cellROIStability;
                        cellROIRandomizedError = app.cellROIRandomizedStability;
                        save([app.rootDir,'cellROIData.mat'],'cellROIError','cellROIRandomizedError')

                        % Display
                        disp('     Cell ROI stability data have been saved. Please make sure your current ROIs have been saved as well.')
                        fprintf('\n')

                        app.calculateButton.BackgroundColor = [0.96 0.96 0.96];
                        app.calculateButton.FontColor = [0 0 0];

                        x = cellROIError(:,:,1);
                        y = squeeze(prctile(cellROIRandomizedError(:,:,:,1),95,2));
                        f = x > y;
                        f = sum(f,1);

                        fractions = [num2str(f(1)),' (',num2str(100*f(1)/size(app.cellROIs,1),2),'%)'];
                        for i=2:length(f)
                            fractions = [fractions,', ',num2str(f(i)),' (',num2str(100*f(i)/size(app.cellROIs,1),'%.1f'),'%)'];
                        end
                        message = {'Finished!';'';
                                   ['Out of ',num2str(size(app.cellROIs,1)),' ROIs:'];
                                   fractions;
                                   ' were found to be stable in subsequent experimental sessions.'};
                        d = dialog('Position',[450 470 400 180],'Name','ROI stability');
                        uicontrol('Parent',d,'Style','text','Position',[25 70 350 85],'String',message);
                        uicontrol('Parent',d,'Position',[150 25 100 35],'String','OK','Callback','delete(gcf)');
                        clear('d')

                end

            elseif isempty(app.cellROIs)

                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','No cell ROIs have been defined yet.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuUIFigure = uifigure('Visible', 'off');
            app.MenuUIFigure.Name = 'Menu';
            app.MenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @MenuUIFigureCloseRequest, true);
            app.activityoffsetEditFieldLabel = uilabel(app.MenuUIFigure);
            app.activityoffsetEditFieldLabel.Text = 'activity offset';
            app.windowsizeEditFieldLabel = uilabel(app.MenuUIFigure);
            app.windowsizeEditFieldLabel.Text = 'window size';
            app.minpixelsEditFieldLabel = uilabel(app.MenuUIFigure);
            app.minpixelsEditFieldLabel.Text = 'min pixels';
            app.maxpixelsEditFieldLabel = uilabel(app.MenuUIFigure);
            app.maxpixelsEditFieldLabel.Text = 'max pixels';
            app.HmaximaLabel = uilabel(app.MenuUIFigure);
            app.HmaximaLabel.Text = 'H maxima';
            app.activitythresholdEditFieldLabel = uilabel(app.MenuUIFigure);
            app.activitythresholdEditFieldLabel.Text = 'activity threshold';
            app.diskradiusEditFieldLabel = uilabel(app.MenuUIFigure);
            app.diskradiusEditFieldLabel.Text = 'disk radius';
            app.activitySliderLabel = uilabel(app.MenuUIFigure);
            app.activitySliderLabel.Text = 'activity';
            app.calculateButton = uibutton(app.MenuUIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.ROIStabilityLabel = uilabel(app.MenuUIFigure);
            app.ROIStabilityLabel.Text = 'ROI Stability';
            app.saveButton = uibutton(app.MenuUIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.SaveCellDataLabel = uilabel(app.MenuUIFigure);
            app.SaveCellDataLabel.Text = 'Save Cell Data';
            app.resetButton = uibutton(app.MenuUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.zoomoutButton = uibutton(app.MenuUIFigure, 'push');
            app.zoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoutButtonPushed, true);
            app.zoomoutButton.Text = 'zoom out';
            app.zoominButton = uibutton(app.MenuUIFigure, 'state');
            app.zoominButton.ValueChangedFcn = createCallbackFcn(app, @zoominButtonValueChanged, true);
            app.zoominButton.Text = 'zoom in';
            app.refineROIsButton = uibutton(app.MenuUIFigure, 'state');
            app.refineROIsButton.ValueChangedFcn = createCallbackFcn(app, @refineROIsButtonValueChanged, true);
            app.refineROIsButton.Text = 'refine ROIs';
            app.RefineCellROIsLabel = uilabel(app.MenuUIFigure);
            app.RefineCellROIsLabel.Text = 'Refine Cell ROIs';
            app.detectcellsButton = uibutton(app.MenuUIFigure, 'push');
            app.detectcellsButton.ButtonPushedFcn = createCallbackFcn(app, @detectcellsButtonPushed, true);
            app.detectcellsButton.Text = 'detect cells';
            app.diskradiusEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.diskradiusEditField.ValueChangedFcn = createCallbackFcn(app, @diskradiusEditFieldValueChanged, true);
            app.diskradiusEditField.Value = 5;
            app.activityoffsetEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.activityoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @activityoffsetEditFieldValueChanged, true);
            app.activityoffsetEditField.Value = -0.05;
            app.activitythresholdEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.activitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @activitythresholdEditFieldValueChanged, true);
            app.HmaximaEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.HmaximaEditField.ValueChangedFcn = createCallbackFcn(app, @HmaximaEditFieldValueChanged, true);
            app.maxpixelsEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.maxpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @maxpixelsEditFieldValueChanged, true);
            app.maxpixelsEditField.Value = 400;
            app.minpixelsEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.minpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @minpixelsEditFieldValueChanged, true);
            app.minpixelsEditField.Value = 200;
            app.windowsizeEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.windowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @windowsizeEditFieldValueChanged, true);
            app.windowsizeEditField.Value = 100;
            app.AutomatedCellDetectionLabel = uilabel(app.MenuUIFigure);
            app.AutomatedCellDetectionLabel.Text = 'Automated Cell Detection';
            app.projectionSliderLabel = uilabel(app.MenuUIFigure);
            app.projectionSliderLabel.Text = 'projection';
            app.cellsSliderLabel = uilabel(app.MenuUIFigure);
            app.cellsSliderLabel.Text = 'cells';
            app.cellsSlider = uislider(app.MenuUIFigure);
            app.cellsSlider.ValueChangingFcn = createCallbackFcn(app, @cellsSliderValueChanging, true);
            app.cellsSlider.Value = 100;
            app.projectionSlider = uislider(app.MenuUIFigure);
            app.projectionSlider.ValueChangingFcn = createCallbackFcn(app, @projectionSliderValueChanging, true);
            app.projectionSlider.Value = 100;
            app.activitySlider = uislider(app.MenuUIFigure);
            app.activitySlider.ValueChangingFcn = createCallbackFcn(app, @activitySliderValueChanging, true);
            app.activitySlider.Value = 100;
            app.FieldofViewLabel = uilabel(app.MenuUIFigure);
            app.FieldofViewLabel.Text = 'Field of View';
            % Show the figure after all components are created
            app.MenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defineCellROIs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuUIFigure)
        end
    end
end